classdef ButtonCODEOFFICIALREALESTOFREALREALVERSION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes_2  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoBoard % Description

        %current problem: not checking for button press until the end of
        %the cycle, should check all the time
        % not resetting

        %button properties

        buttonLeft = 1; %button left is not pressed
        buttonLeftPin = 'D8'; %pin number on ardiono

        buttonMiddle = 1; %button middle is not pressed
        buttonMiddlePin = 'D13'; %pin number

        buttonRight = 1; %button right is not pressed
        buttonRightPin = 'D12'; %pin number


        %credits: created by Mary Morris, 
        % Help from: FYELIC RedVest support, ChatGPT for help with image object syntax
     

        %to add: 2 minute timer that starts after the slides play,
        %automatically ends the game if the time is up

        %make everything a function: have an intro function, gameplay
        %function, have an outro function, timer function, points function

        %pseudocode
        %phase 1:
            %game starts and shows 3 messages
            %default game begins: while 2 minutes is still going and health
            %>0
            %2 minute timer begins
            %health is 100
            %if the user clicks wrong, they lose 10 health
            %gets faster every 30 seconds
            %when health is 0 or game has ended, end
            %display results
           
        %phase 2: send feedback to matlab

       
        score = 0; % keep score of total accumulated points
            %score is different based on level
        level = 1;
            %  levels 1-4 
        gameSpeed = 0.03; %controls the speed of the blocks based on level
        healthBar = 100; %default health = 100/100
            %will be connected to arduino

            %all for showing the blocks
        config = 1;
        hLeft;
        hMid;
        hRight;
        val;

        buttonPressed = 0; %flag for if button has been pressed
        gameplay = 1; %game is playing


        goodClick = 0; %if they got it wrong, this turns to 1 and they lose a health point
            
            %timing
        %new 120 second timer
        TimeRemaining = 120;
        myTimer;
    end
    
    methods (Access = private)
        
        function UpdateTimer(app) %function to update the timer
           app.TimeRemaining = app.TimeRemaining - 1;
    
            if app.TimeRemaining <= 0
                app.gameplay = 0;
                stop(app.myTimer); % Immediately stop timer
                delete(app.myTimer); % Delete it
            end

        % Display time (optional)
        disp(['Time left: ', num2str(app.TimeRemaining), 's']);

        % End game if time runs out
        if app.TimeRemaining <= 0
            stop(app.myTimer);      % Stop the timer
            app.gameplay = 0;       % End the game loop
            disp('Game over! Time expired.');
            
            % Show "Time's Up!" message (optional)
            img = imread('time_up.png'); % Replace with your image
            imshow(img, 'Parent', app.UIAxes_2);         
        end
        end

        
        function stopAndDeleteTimer(app)
            if isvalid(app.myTimer)
            stop(app.myTimer);
            delete(app.myTimer);
            end
            
        end
        
        function ButtonControl(app)
                
             %this will show if the left, middle, or right button was pressed
            %always be chekcing
               app.val = 0; 
 
                        %constant check
                        app.buttonLeft = readDigitalPin(app.arduinoBoard, app.buttonLeftPin);
                        app.buttonMiddle = readDigitalPin(app.arduinoBoard, app.buttonMiddlePin);
                        app.buttonRight = readDigitalPin(app.arduinoBoard, app.buttonRightPin);

                        if(app.buttonLeft == 0) %left button is pressed
                           %app.buttonPressed = 1; %a button has been pressed
                           app.val = 1;
                           
                                                          
                        elseif(app.buttonRight == 0) %right button is pressed
                            %app.buttonPressed = 1; %a button has been pressed
                            app.val = 2;
                            
                        elseif(app.buttonMiddle == 0)
                            %app.buttonPressed = 1; %set it to has been pressed
                            app.val = 3;

                        end
             

            %val is if it's left, right, or zero!!

            %left = 1
            %right = 2
            %middle = 3

            %number passed in indicates which button was pressed, it does
            %the qwe code based on this
            
            % Read button states from Arduino
            
            %this function converts an external button press to qwe
            %replaces the previous qwe function
      

            %image variables
            Guard = 'guard.png';

                %which one being used

                if(app.val ==1)
                    if app.config == 1 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        set(app.hLeft, 'CData', keyResult); 
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        app.buttonPressed = 1;
                    elseif (app.config == 2 || app.config == 3) && app.buttonPressed == 0
                        keyResult = imread(Guard);
                        %lose health
                        app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hLeft, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    end
                elseif(app.val == 2)
                      if app.config == 1 && app.buttonPressed == 0
                        keyResult = imread(Guard);
                              app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hMid, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    elseif app.config == 2 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        set(app.hMid, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    elseif app.config == 3 && app.buttonPressed == 0
                        keyResult = imread(Guard);
                              app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hMid, 'CData', keyResult);
                        app.buttonPressed = 1;
                      end
                elseif(app.val ==3)
                     if(app.config == 1 || app.config == 2) && app.buttonPressed == 0
                        keyResult = imread(Guard);
                        set(app.hRight, 'CData', keyResult); 
                                app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        app.buttonPressed = 1;
                    elseif app.config == 3 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        set(app.hRight, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    end
                end
             
                end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoBoard = arduino('COM6','UNO');

            %initialize pins

             
            configurePin(app.arduinoBoard, app.buttonLeftPin, 'Pullup');
            configurePin(app.arduinoBoard, app.buttonMiddlePin, 'Pullup');
            configurePin(app.arduinoBoard, app.buttonRightPin, 'Pullup');
             
               %intro omages
                img = imread('C:\Users\rainb\OneDrive - Northeastern University\2024-2025\Cornerstone\P2\Matlab\MicrosoftTeams-image (3) - Copy.png');
                imshow(img, 'Parent', app.UIAxes_2);
                pause(3);
                % Clear the axes (remove the image)
                cla(app.UIAxes_2);
                pause(3);
 %show message 1
                img = imread('C:\Users\rainb\OneDrive - Northeastern University\2024-2025\Cornerstone\P2\Matlab\MicrosoftTeams-image (3) - Copy.png');
                imshow(img, 'Parent', app.UIAxes_2);
                pause(3);                cla(app.UIAxes_2); %clear image


                %non-default

                 %start game w/initial blocks 


                 %start the 2-minute timer
                 % Create and start the 2-minute timer
        
                app.myTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1,'TimerFcn', @(~,~) app.UpdateTimer(),'StartDelay', 1);
       
                  % Cleanup when figure closes
                    addlistener(app.UIFigure, 'ObjectBeingDestroyed', ...
                        @(~,~) stopAndDeleteTimer(app));

                start(app.myTimer); % Start the timer

    GoodPic = 'Pig!.png';
    BadPic = 'BadARB.png';

          %blocks initial
          imLeft = imread(GoodPic);
          imMid = imread(BadPic);
          imRight = imread(GoodPic);
          app.config = 2; %default config


          % Define positions (Start HIGH at y = 600)
            xLeft = [0 110];
            xMid = [120 220];
            xRight = [230 330];
            
            yStart = 10; % Start HIGH at y = 600
            yEnd = 640;    % End at the bottom
            
            % Set UIAxes limits to allow full animation range
            axis(app.UIAxes_2, [0 400 0 700]); % X range [0, 400], Y range [0, 700]
            hold(app.UIAxes_2, 'on'); % Allow multiple images to be drawn

            app.hLeft = imshow(imLeft, 'Parent', app.UIAxes_2, 'XData', xLeft, 'YData', [yStart yStart+100]);
            app.hMid = imshow(imMid, 'Parent', app.UIAxes_2, 'XData', xMid, 'YData', [yStart yStart+100]);
            app.hRight = imshow(imRight, 'Parent', app.UIAxes_2, 'XData', xRight, 'YData', [yStart yStart+100]);


            %
              while (app.gameplay == 1 && app.healthBar > 0 && app.TimeRemaining > 0) 


                for y = linspace(yStart, yEnd, 100) % Smooth falling
                % Update Y positions
                %always be checking for a button press

                %always checking
                %if it has in fact been pressed, then save which one has
                %been pressed, stop checking and send to the button check
                %function

%make new variables to stop checking
                
                ButtonControl(app);

                set(app.hLeft, 'YData', [y y+100]);
                set(app.hMid, 'YData', [y y+100]);
                set(app.hRight, 'YData', [y y+100]);

                drawnow;

                pause(0.0001); % Control falling speed


                end

                    %reset app.val
                app.val = 0;
                %done moving, check for complete miss
                
                if app.buttonPressed == 0 
                    app.healthBar = app.healthBar - 10; %lose health bc u missed
                end

                app.buttonPressed = 0; %reset the button!!
               %cla(app.UIAxes); %clear image
                pause(app.gameSpeed/2); % Pause briefly before restarting
                %randomize the image

                %reset buttons
                app.buttonLeft = 1;
                app.buttonRight = 1;
                app.buttonMiddle = 1;

    
                % Randomize the image configuration
                randConfig = randi([1, 3]); % Random number between 1 and 3

                if randConfig == 1
                    imNewLeft = imread(BadPic);
                    imNewMid = imread(GoodPic);
                    imNewRight = imread(GoodPic);
                    app.config = 1;
                elseif randConfig == 2
                     imNewLeft = imread(GoodPic);
                     imNewMid = imread(BadPic);
                     imNewRight = imread(GoodPic);
                     app.config = 2;
                 elseif randConfig == 3
                     imNewLeft = imread(GoodPic);
                     imNewMid = imread(GoodPic);
                     imNewRight = imread(BadPic);
                     app.config = 3;

                end

                  %update the images
                set(app.hLeft, 'CData', imNewLeft);  % Update the left image
                set(app.hMid, 'CData', imNewMid);    % Update the middle image
                set(app.hRight, 'CData', imNewRight); % Update the right image  
              
                %TO DO: ADD FLAG BOOLEAN TO SHOW IF THE IMAGE HAS BEEN
                %CHAGED YET!!

              end

               %delete timer

               % Cleanup when game ends
                    if isvalid(app.myTimer)
                        stop(app.myTimer);
                        delete(app.myTimer); % Prevent memory leaks
                    end


              %now game has ended


              %clear screen

              cla(app.UIAxes_2);
              %display message and points results
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
             %image variables
            Guard = 'guard.png';

            switch key
                case 'q' % Left key press
                  
                    if app.config == 1 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        set(app.hLeft, 'CData', keyResult); 
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        app.buttonPressed = 1;
                    elseif (app.config == 2 || app.config == 3) && app.buttonPressed == 0
                        keyResult = imread(Guard);
                        %lose health
                        app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hLeft, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    end
                case 'w' % Middle key press
                  
                    if app.config == 1 && app.buttonPressed == 0
                        keyResult = imread(Guard);
                              app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hMid, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    elseif app.config == 2 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        set(app.hMid, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    elseif app.config == 3 && app.buttonPressed == 0
                        keyResult = imread(Guard);
                              app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        set(app.hMid, 'CData', keyResult);
                        app.buttonPressed = 1;
                    end
                case 'e' % Right key press
                    if(app.config == 1 || app.config == 2) && app.buttonPressed == 0
                        keyResult = imread(Guard);
                        set(app.hRight, 'CData', keyResult); 
                                app.healthBar = app.healthBar - 10; %lose 10 health points for missing
                        app.buttonPressed = 1;
                    elseif app.config == 3 && app.buttonPressed == 0
                        keyResult = imread('GoodPill.png');
                        app.score = app.score + (app.level*10); % app gains score based on the level
                        set(app.hRight, 'CData', keyResult); 
                        app.buttonPressed = 1;
                    end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonCODEOFFICIALREALESTOFREALREALVERSION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end