classdef FunctionalJoystickCodeUgly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes_2  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoBoard % Description

        %button properties
          
        buttonLeft = 1; %button left is not pressed
        lastButtonLeft = 1; %last left button state
        buttonLeftPin = 'D13'; %pin number on ardiono


        buttonRight = 1; %button right is not pressed
        lastButtonRight = 1; %last right button state
        buttonRightPin = 'D12'; %pin number

        %formatting

        xMid;
        xLeft;
        xRight;

        xPositions;
          

        %credits: created by Mary Morris, 
        % Help from: FYELIC RedVest support, ChatGPT for help with image object syntax


        score = 0; % keep score of total accumulated points
            %score is different based on level
        level = 1;
            %  levels 1-4 
        gameSpeed = 0.03; %controls the speed of the blocks based on level
        healthBar = 100; %default health = 100/100
            %will be connected to arduino

            %all for showing the blocks

        config = 1; %which configuration they r in

        hOne; %image of obstacle 1
        hTwo; %image of obstacle 2
        hPlayer; %image of player

        playerPosition = 2; %playerPosition from the joystick
        newMovement = 0; %see if playerPosition needs to be updated %default is currently no new movement


        gameplay = 1; %game is playing
        goodClick = 0; %if they got it wrong, this turns to 1 and they lose a health point
            
            %timing
        %new 120 second timer
        TimeRemaining = 120;
        myTimer;

        %images
        Guard = 'guard.png';

    end
    
    methods (Access = private)
        
        function UpdateTimer(app) %function to update the timer
           app.TimeRemaining = app.TimeRemaining - 1;
    
            if app.TimeRemaining <= 0
                app.gameplay = 0;
                stop(app.myTimer); % Immediately stop timer
                delete(app.myTimer); % Delete it
            end

        % Display time 
        disp(['Time left: ', num2str(app.TimeRemaining), 's']);

        % End game if time runs out
        if app.TimeRemaining <= 0
            stop(app.myTimer);      % Stop the timer
            app.gameplay = 0;       % End the game loop
            disp('Game over! Time expired.');
            
            % Show "Time's Up!" message 
            img = imread('time_up.png'); % Replace with your image
            imshow(img, 'Parent', app.UIAxes_2);         
        end
        end

        
        function stopAndDeleteTimer(app)
            if isvalid(app.myTimer)
            stop(app.myTimer);
            delete(app.myTimer);
            end
            
        end
        
        function JoystickOutput(app) %function to show the joystick results on the screen
      
            
                %only update the position of the player if there has been a
                %new move and also maybe if the blocks y are above a
                %certain point
                %which one being used

                if(app.playerPosition ==1) %if the player is on the left
                    %display the player on the left
                    set(app.hPlayer, 'XData', app.xLeft);

                elseif(app.playerPosition == 2) %if the player is in the middle
                    set(app.hPlayer, 'XData', app.xMid);

                elseif(app.playerPosition ==3) %if the player is on the right
                     set(app.hPlayer, 'XData', app.xRight);
                     

                end

                app.newMovement = 0; %reset joystick
             
                end
                    
        function JoystickEvaluate(app)      
            %when the blocks have reached where the joystick is, the
            %success of the cycle is evaluated


            %player position 1 (left)

            if(app.playerPosition == 1)
                
                    if(app.config == 2)
                        %get point
                    else
                        %lose health
                        %show graphic
                        
                    end

                
            end
            
            if(app.playerPosition == 2)
                
                    if(app.config == 1)
                        %get point
                    else
                        %lose health
                        %show graphic

                    end
                    
                
            end

            if(app.playerPosition == 3)
                 if(app.config == 2)
                     %get point
                 else
                     %lose health
                     %show graphic
                 end


            end


        end
            
        
        function JoystickRead(app)

            %tracks the position of the joystick
            %check position all the time
            %check if button is on or off

            %can delete app.val
                
             %this will show if the left, middle, or right button was pressed
            %always be chekcing
              % app.val = 0; 
 
           %constant check
           app.buttonLeft = readDigitalPin(app.arduinoBoard, app.buttonLeftPin);
           app.buttonRight = readDigitalPin(app.arduinoBoard, app.buttonRightPin);


        if(app.buttonLeft == 0 && app.lastButtonLeft == 1) %left joystick press and it wasn't pressed a a second ago
        app.playerPosition = max(1, app.playerPosition - 1);
        app.newMovement = 1;
        end
        
       
        if(app.buttonRight == 0 && app.lastButtonRight == 1)
            app.playerPosition = min(3, app.playerPosition + 1);
            app.newMovement = 1;
        end
                        
       
        app.lastButtonLeft = app.buttonLeft;
        app.lastButtonRight = app.buttonRight;
                        
      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoBoard = arduino('COM6','UNO');

            app.xPositions = {[0 110],[120 220],[230 330] };  

            %initialize pins

             
            configurePin(app.arduinoBoard, app.buttonLeftPin, 'Pullup');
           % configurePin(app.arduinoBoard, app.buttonMiddlePin, 'Pullup');
            configurePin(app.arduinoBoard, app.buttonRightPin, 'Pullup');
             
               %intro omages
                img = imread('C:\Users\rainb\OneDrive - Northeastern University\2024-2025\Cornerstone\P2\Matlab\MicrosoftTeams-image (3) - Copy.png');
                imshow(img, 'Parent', app.UIAxes_2);
                pause(3);
                % Clear the axes (remove the image)
                cla(app.UIAxes_2);
                pause(3);
 %show message 1
                img = imread('C:\Users\rainb\OneDrive - Northeastern University\2024-2025\Cornerstone\P2\Matlab\MicrosoftTeams-image (3) - Copy.png');
                imshow(img, 'Parent', app.UIAxes_2);
                pause(3);                cla(app.UIAxes_2); %clear image


                %non-default

                 %start game w/initial blocks 


                 %start the 2-minute timer
                 % Create and start the 2-minute timer
        
                app.myTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1,'TimerFcn', @(~,~) app.UpdateTimer(),'StartDelay', 1);
       
                  % Cleanup when figure closes
                    addlistener(app.UIFigure, 'ObjectBeingDestroyed', ...
                        @(~,~) stopAndDeleteTimer(app));

                start(app.myTimer); % Start the timer

    GoodPic = 'Pig!.png';
    BadPic = 'BadARB.png';


          %blocks initial
          imOne = imread(GoodPic); %default with the
          imTwo = imread(GoodPic);
          imPlayer = imread(BadPic); %image is the bad player
          app.config = 1; %default with the blocks 

        %  YXY possible combinations %combination 1
        %  YYX %combination 2
         % XYY %combination 3


          % Define positions (Start HIGH at y = 600)
            app.xLeft = [0 110];
            app.xMid = [120 220];
            app.xRight = [230 330];
            
            yStart = 10; % Start HIGH at y = 600
            yEnd = 400;    % End at the bottom
            
            % Set UIAxes limits to allow full animation range
            axis(app.UIAxes_2, [0 400 0 700]); % X range [0, 400], Y range [0, 700]
            hold(app.UIAxes_2, 'on'); % Allow multiple images to be drawn

            app.hOne = imshow(imOne, 'Parent', app.UIAxes_2, 'XData', app.xLeft, 'YData', [yStart yStart+100]); %shows in the left position
            app.hTwo = imshow(imTwo, 'Parent', app.UIAxes_2, 'XData', app.xRight, 'YData', [yStart yStart+100]);
           

           %make middle
            
            
            app.hPlayer = imshow(imPlayer, 'Parent', app.UIAxes_2, 'XData', app.xMid, 'YData', [300 400]); %shows player in the middle
            %
              while (app.gameplay == 1 && app.healthBar > 0 && app.TimeRemaining > 0) 


                for y = linspace(yStart, yEnd, 100) % Smooth falling
                        
                     JoystickRead(app);

                     if(app.newMovement == 1) %if there's been a new movement
                     JoystickOutput(app); %check
                     end


                     set(app.hOne, 'YData', [y y+100]);
                     set(app.hTwo, 'YData', [y y+100]);

                     drawnow;

                     if(y==300) %the blocks are at 300
                         JoystickEvaluate(app);
                     end

                     pause(0.0001); % Control falling speed
                % Update Y positions
                %always be checking for a button press

                %always checking
                %if it has in fact been pressed, then save which one has
                %been pressed, stop checking and send to the button check
                %function

%make new variables to stop checking
                %check joystick


                end

                    %reset the configuration
                app.playerPosition = 0; %put player in the middle as default??
                %done moving, check for complete miss
                
                set(app.hPlayer, 'XData', app.xMid);

                % 
                % if app.buttonPressed == 0 
                %     app.healthBar = app.healthBar - 10; %lose health bc u missed
                % end

               % app.buttonPressed = 0; %reset buttons?????
               %cla(app.UIAxes); %clear image
                pause(app.gameSpeed/2); % Pause briefly before restarting
                %randomize the image

                %reset buttons ????


                % Randomize the image configuration
                randConfig = randi([1, 3]); % Random number between 1 and 3

                if randConfig == 1 %lateral, left on left, right on right
                    set(app.hOne, 'XData', app.xLeft);
                    set(app.hTwo, 'XData', app.xRight);                  
                    app.config = 1;
                elseif randConfig == 2 %left in middle, right on right
                    set(app.hOne, 'XData', app.xMid);
                    set(app.hTwo, 'XData', app.xRight);                  
                    app.config = 2;
                elseif randConfig == 3 %left on left, right in middle
                    set(app.hOne, 'XData', app.xLeft);
                    set(app.hTwo, 'XData', app.xMid);
                     app.config = 3;
                end

                              
               end

               %delete timer

               % Cleanup when game ends
                    if isvalid(app.myTimer)
                        stop(app.myTimer);
                        delete(app.myTimer); % Prevent memory leaks
                    end


              %now game has ended


              %clear screen

              cla(app.UIAxes_2);
              %display message and points results
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
             %image variables
            Guard = 'guard.png';

            switch key
                case 'q' % Left key press
                 
                app.playerPosition = max(1, app.playerPosition - 1);
                app.newMovement = 1;


                case 'e' % Right key press

                   app.playerPosition = min(3, app.playerPosition + 1);
                   app.newMovement = 1;

                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionalJoystickCodeUgly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end